classdef demo_experiment_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        AgentTypeDropDownLabel  matlab.ui.control.Label
        AgentTypeDropDown       matlab.ui.control.DropDown
        SimulateButton          matlab.ui.control.Button
        UITable                 matlab.ui.control.Table
        GrabXYfromplotButton    matlab.ui.control.Button
        CleartableButton        matlab.ui.control.Button
        SavetableButton         matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            % parameters
            n_agents = 10;
            n_timesteps = 50;
            xy_max = 100;
            start_pos_min = 0.4*xy_max;
            start_pos_max = 0.6*xy_max;
            type_of_agents = app.AgentTypeDropDown.Value;
            if isequal(type_of_agents, 'levy') | isequal(type_of_agents, 'diffusion')
                env_type = 'empty'; 
            else
                env_type = 'pheromone'; 
            end
            sensory_range = 10; 
            plot_realtime = 0; % it'll run faster if this is 0
            
            XY_all = simulate_agents(n_agents, n_timesteps, xy_max, start_pos_min, start_pos_max, ...
                type_of_agents, env_type, sensory_range, plot_realtime);
            
            % trajectory plot
            set(0, 'CurrentFigure', app.UIFigure)
            cla
            plot_trajectories(XY_all, xy_max)
        end

        % Button pushed function: GrabXYfromplotButton
        function GrabXYfromplotButtonPushed(app, event)
            set(0, 'CurrentFigure', app.UIFigure)
            [length_of_data, ~] = size(app.UITable.Data);
            % for ginput to work, need app.UIFigure PARENT/CHILD
            % HandleVisibility to be on
            app.UITable.Data(length_of_data+1,:) =  ginput(1);
        end

        % Button pushed function: CleartableButton
        function CleartableButtonPushed(app, event)
            app.UITable.Data = zeros(0,2);
        end

        % Button pushed function: SavetableButton
        function SavetableButtonPushed(app, event)
            % Get the name of the file that the user wants to save.
            startingFolder = pwd;
            defaultFileName = fullfile(startingFolder, '*.xlsx');
            [baseFileName, folder] = uiputfile(defaultFileName, 'Specify a filename ');
            % Combine the folder and base file name into the full file name.
            fullFileName = fullfile(folder, baseFileName);
            % Write the data out to the filename the user has chosen.
            xlswrite(fullFileName, app.UITable.Data);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AgentTypeDropDownLabel = uilabel(app.UIFigure);
            app.AgentTypeDropDownLabel.Text = 'Agent Type';
            app.AgentTypeDropDown = uidropdown(app.UIFigure);
            app.AgentTypeDropDown.Items = {'diffusion', 'levy', 'seek_odor', 'elm_try_something_new'};
            app.AgentTypeDropDown.Value = 'elm_try_something_new';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate!';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X'; 'Y'};
            app.UITable.RowName = {};
            app.GrabXYfromplotButton = uibutton(app.UIFigure, 'push');
            app.GrabXYfromplotButton.ButtonPushedFcn = createCallbackFcn(app, @GrabXYfromplotButtonPushed, true);
            app.GrabXYfromplotButton.Text = 'Grab X,Y from plot';
            app.CleartableButton = uibutton(app.UIFigure, 'push');
            app.CleartableButton.ButtonPushedFcn = createCallbackFcn(app, @CleartableButtonPushed, true);
            app.CleartableButton.Text = 'Clear table';
            app.SavetableButton = uibutton(app.UIFigure, 'push');
            app.SavetableButton.ButtonPushedFcn = createCallbackFcn(app, @SavetableButtonPushed, true);
            app.SavetableButton.Text = 'Save table';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo_experiment_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end